classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TextArea                     matlab.ui.control.TextArea
        FrecuenciainferiorEditField  matlab.ui.control.NumericEditField
        FrecuenciainferiorEditFieldLabel  matlab.ui.control.Label
        FrecuenciasuperiorEditField  matlab.ui.control.NumericEditField
        FrecuenciasuperiorEditFieldLabel  matlab.ui.control.Label
        HzLabel_2                    matlab.ui.control.Label
        HzLabel                      matlab.ui.control.Label
        ListBox_2                    matlab.ui.control.ListBox
        ListBox                      matlab.ui.control.ListBox
        CargarAudioButton            matlab.ui.control.Button
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        tipo % Description
        sa % Audio
        aud %Audio
        Fs %Frecuencia de muestreo
        mono %Audio monofonico
        nData %Cantidad de datos
        
    end
    
    methods (Access = public)
        
        function filterTopology = func(app)
            if app.ListBox.Value == 'Pasa Alta'
                app.tipo = 'high'
                disp(app.tipo)
            elif app.ListBox.Value == 'Pasa Baja'
                app.tipo = 'high'
            end
            
        end
        
        function filterType = func2(app)
            if app.ListBox_2ValueChanged == 'Enventanado (FIR)'
                disp('hola mundo')
            end

            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarAudioButton
        function CargarAudioButtonPushed(app, event)
            app.sa = uigetfile('*.mp3');
            disp(app.sa)
            [app.aud, app.Fs] = audioread(app.sa);
            app.mono = (app.aud(:,1)+app.aud(:,2))/2;
            app.nData = 0:length(app.aud)-1;       
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
                       
        end

        % Value changed function: FrecuenciasuperiorEditField
        function FrecuenciasuperiorEditFieldValueChanged(app, event)
            value = app.FrecuenciasuperiorEditField.Value
            
        end

        % Value changed function: FrecuenciainferiorEditField
        function FrecuenciainferiorEditFieldValueChanged(app, event)
            value = app.FrecuenciainferiorEditField.Value
            
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            value = app.ListBox_2.Value;
            if value == 'Enventanado (FIR)'
                disp('hola mundo')
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Respuesta en frecuencia')
            xlabel(app.UIAxes, 'Frecuencia (Hz)')
            ylabel(app.UIAxes, 'H(v) (dB)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Señal original vs Señal filtrada')
            xlabel(app.UIAxes_2, 'Tiempo (S)')
            ylabel(app.UIAxes_2, 'Amplitud')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Densidad espectral de energia')
            xlabel(app.UIAxes_3, 'Frecuencia (Hz)')
            ylabel(app.UIAxes_3, 'dB/Hz')
            zlabel(app.UIAxes_3, 'Z')
            app.CargarAudioButton = uibutton(app.UIFigure, 'push');
            app.CargarAudioButton.ButtonPushedFcn = createCallbackFcn(app, @CargarAudioButtonPushed, true);
            app.CargarAudioButton.Text = 'Cargar Audio';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Pasa Alta', 'Pasa Baja', 'Rechaza Bandas', 'Pasa Bandas'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Pasa Alta';
            app.ListBox_2 = uilistbox(app.UIFigure);
            app.ListBox_2.Items = {'Enventanado (FIR)', 'Muestreo en Frecuencia (FIR)', 'Parks-McClellan (FIR)', 'Butterworth (IIR)', 'Chebyshov I (IIR)', 'Chebyshov II (IIR)', 'Elíptico (IIR)'};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = 'Parks-McClellan (FIR)';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = 'Hz';
            app.FrecuenciasuperiorEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciasuperiorEditFieldLabel.Text = 'Frecuencia superior';
            app.FrecuenciasuperiorEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciasuperiorEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciasuperiorEditFieldValueChanged, true);
            app.FrecuenciainferiorEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciainferiorEditFieldLabel.Text = 'Frecuencia inferior';
            app.FrecuenciainferiorEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciainferiorEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciainferiorEditFieldValueChanged, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'PASOS: 1. Ingrese el audio, 2. Ingrese el tipo de filtro, 3. Ingrese las frecuencias de corte segun sea el caso, 4. Ingrese la topologia del filtro'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end